classdef Forward_kin_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OutputMatrixPanel              matlab.ui.container.Panel
        UITable4                       matlab.ui.control.Table
        JointvariablesPanel            matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        RunButton                      matlab.ui.control.Button
        WorkspacePlotAxesPanel         matlab.ui.container.Panel
        XminEditFieldLabel             matlab.ui.control.Label
        XminEditField                  matlab.ui.control.NumericEditField
        XmaxEditFieldLabel             matlab.ui.control.Label
        XmaxEditField                  matlab.ui.control.NumericEditField
        YminEditFieldLabel             matlab.ui.control.Label
        YminEditField                  matlab.ui.control.NumericEditField
        YmaxEditFieldLabel             matlab.ui.control.Label
        YmaxEditField                  matlab.ui.control.NumericEditField
        ZmaxEditFieldLabel             matlab.ui.control.Label
        ZmaxEditField                  matlab.ui.control.NumericEditField
        ZminEditFieldLabel             matlab.ui.control.Label
        ZminEditField                  matlab.ui.control.NumericEditField
        DHparametersandjointtypePanel  matlab.ui.container.Panel
        NumberofLinksEditFieldLabel    matlab.ui.control.Label
        NumberofLinksEditField         matlab.ui.control.NumericEditField
        ThetaEditFieldLabel            matlab.ui.control.Label
        ThetaEditField                 matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        dEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        alphaEditFieldLabel            matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.NumericEditField
        LinkEditFieldLabel             matlab.ui.control.Label
        LinkEditField                  matlab.ui.control.NumericEditField
        FillRowButton                  matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        UITable3                       matlab.ui.control.Table
        JointTypeEditFieldLabel        matlab.ui.control.Label
        JointTypeEditField             matlab.ui.control.NumericEditField
        forrevoluteand1forprismaticLabel  matlab.ui.control.Label
        JointVariableEditFieldLabel    matlab.ui.control.Label
        JointVariableEditField         matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            dh=app.UITable2.Data
          assignin('base','dh',dh)
          type_joint=app.UITable3.Data'
          assignin('base','type_joint',type_joint)
          Workspace=[app.XminEditField.Value app.XmaxEditField.Value app.YminEditField.Value app.YmaxEditField.Value app.ZminEditField.Value app.ZmaxEditField.Value]
          assignin('base','Workspace',Workspace)
          q=app.UITable.Data';
          assignin('base','q',q)
          for i=1:length(type_joint)
    if type_joint(i)==0
        q(:,i)=q(:,i)*pi/180;
    end
end
for i=1:length(type_joint)
        if type_joint(1,i)==0
        L(i)=Link([dh(i,1),dh(i,2),dh(i,3),dh(i,4)])
        else
        L(i)=Link([dh(i,1),dh(i,2),dh(i,3),dh(i,4),1])
        end
    end
R = SerialLink(L);
T=R.fkine(q);
ST=[T.n';T.o';T.a';T.t'];
T=ST';
R.plot(q,'workspace',Workspace)
app.UITable4.Data=T
          assignin('base','T',app.UITable4.Data)
        end

        % Button pushed function: FillRowButton
        function FillRowButtonPushed(app, event)
            app.UITable2.Data(app.LinkEditField.Value,:) = [app.ThetaEditField.Value,app.dEditField.Value,app.aEditField.Value,app.alphaEditField.Value]
            app.UITable3.Data(app.LinkEditField.Value,1) = [app.JointTypeEditField.Value]
            app.UITable.Data(app.LinkEditField.Value,1) = [app.JointVariableEditField.Value]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OutputMatrixPanel = uipanel(app.UIFigure);
            app.OutputMatrixPanel.Title = 'Output Matrix';
            app.UITable4 = uitable(app.OutputMatrixPanel);
            app.UITable4.ColumnName = {'1'; '2'; '3'; '4'};
            app.UITable4.RowName = {};
            app.JointvariablesPanel = uipanel(app.UIFigure);
            app.JointvariablesPanel.Title = 'Joint variables';
            app.UITable = uitable(app.JointvariablesPanel);
            app.UITable.ColumnName = {'q (1 : n)'};
            app.UITable.RowName = {};
            app.RunButton = uibutton(app.JointvariablesPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.WorkspacePlotAxesPanel = uipanel(app.UIFigure);
            app.WorkspacePlotAxesPanel.Title = 'Workspace (Plot Axes)';
            app.XminEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.XminEditFieldLabel.Text = 'X min';
            app.XminEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.XmaxEditFieldLabel.Text = 'X max';
            app.XmaxEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.YminEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.YminEditFieldLabel.Text = 'Y min';
            app.YminEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.YmaxEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.ZmaxEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.ZmaxEditFieldLabel.Text = 'Z max';
            app.ZmaxEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.ZminEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.ZminEditFieldLabel.Text = 'Z min';
            app.ZminEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.DHparametersandjointtypePanel = uipanel(app.UIFigure);
            app.DHparametersandjointtypePanel.Title = 'DH parameters and joint type';
            app.NumberofLinksEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.NumberofLinksEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.dEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.aEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.LinkEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.LinkEditFieldLabel.Text = 'Link';
            app.LinkEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.FillRowButton = uibutton(app.DHparametersandjointtypePanel, 'push');
            app.FillRowButton.ButtonPushedFcn = createCallbackFcn(app, @FillRowButtonPushed, true);
            app.FillRowButton.Text = 'Fill Row';
            app.UITable2 = uitable(app.DHparametersandjointtypePanel);
            app.UITable2.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.DHparametersandjointtypePanel);
            app.UITable3.ColumnName = {'joint type'};
            app.UITable3.RowName = {};
            app.JointTypeEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.JointTypeEditFieldLabel.Text = 'Joint Type';
            app.JointTypeEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.forrevoluteand1forprismaticLabel = uilabel(app.DHparametersandjointtypePanel);
            app.forrevoluteand1forprismaticLabel.Text = '0 for revolute and 1 for prismatic';
            app.JointVariableEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.JointVariableEditFieldLabel.Text = 'Joint Variable';
            app.JointVariableEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Forward_kin_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end