classdef HomePage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RoboticsToolboxPanel           matlab.ui.container.Panel
        FeaturesPanel                  matlab.ui.container.Panel
        HomogeneousTransformationMatrixButton_2  matlab.ui.control.Button
        TransformationMappingButton_2  matlab.ui.control.Button
        RotationMatrixButton           matlab.ui.control.Button
        ForwardKinematicsButton        matlab.ui.control.Button
        WorkspaceButton                matlab.ui.control.Button
        InverseKinematicsButton        matlab.ui.control.Button
        DifferentialKinematicsGeometricJacobianButton  matlab.ui.control.Button
        InverseDifferentialKinematicsButton  matlab.ui.control.Button
        ManipulatorDynamicsButton      matlab.ui.control.Button
        EulerAnglesRotationtoEulerButton  matlab.ui.control.Button
        DifferentialKinematicsNumericJacobianButton  matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        CalculatesthepositionofavectorwrtthebaseframeandplotsthemLabel  matlab.ui.control.Label
        GeneratesarotationmatrixfromEuleranglesandplotsthemLabel  matlab.ui.control.Label
        CalculatesEuleranglesfromarotationmatrixLabel  matlab.ui.control.Label
        CalculatesendeffectorposewrtjointvariablesandplotsthemLabel  matlab.ui.control.Label
        PlotsoperationalworkspaceoftherobotLabel  matlab.ui.control.Label
        Label7                         matlab.ui.control.Label
        ThisgivesyouageometricjacobianwhichhasvariablesasaninputLabel  matlab.ui.control.Label
        Label9                         matlab.ui.control.Label
        GivesjointvelocitiesgiventheendeffectorvelocitiesLabel  matlab.ui.control.Label
        ThisgivesjointvariablesusingJacobianmatrixLabel  matlab.ui.control.Label
        ThisgivesplotofjointsandjointvelocitieswrttimeLabel  matlab.ui.control.Label
        Label13                        matlab.ui.control.Label
        InverseKinematicsusingJacobianButton  matlab.ui.control.Button
        DynamicsequationButton         matlab.ui.control.Button
        GivesthedynamicsequationfortorqueLabel  matlab.ui.control.Label
        GuidetoAccessModuleButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % HomogeneousTransformationMatrixButton_2
        function HomogeneousTransformationMatrixButton_2Pushed(app, event)
            Transformation_Matrix_UPDATED
        end

        % Button pushed function: TransformationMappingButton_2
        function TransformationMappingButton_2Pushed(app, event)
            Transformation_Mapping_UPDATED
        end

        % Button pushed function: RotationMatrixButton
        function RotationMatrixButtonPushed(app, event)
            Rotation_Matrix
        end

        % Callback function
        function EulerAnglesEulertoRotationButtonPushed(app, event)
            eul2rotmat_Final
        end

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
            Forward_kin_app
        end

        % Button pushed function: WorkspaceButton
        function WorkspaceButtonPushed(app, event)
            workspace
        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            Inverse_kin_app
        end

        % Button pushed function: 
        % DifferentialKinematicsGeometricJacobianButton
        function DifferentialKinematicsGeometricJacobianButtonPushed(app, event)
Geometric_Jacobianwith_variablesCorrected
        end

        % Button pushed function: 
        % InverseDifferentialKinematicsButton
        function InverseDifferentialKinematicsButtonPushed(app, event)
            InverseDifferentialKinematicsFinal
        end

        % Button pushed function: ManipulatorDynamicsButton
        function ManipulatorDynamicsButtonPushed(app, event)
            dynamics11
        end

        % Button pushed function: EulerAnglesRotationtoEulerButton
        function EulerAnglesRotationtoEulerButtonPushed(app, event)
            rotmat_to_euler
        end

        % Button pushed function: 
        % DifferentialKinematicsNumericJacobianButton
        function DifferentialKinematicsNumericJacobianButtonPushed(app, event)
            NumericJacobianfinal
        end

        % Button pushed function: 
        % InverseKinematicsusingJacobianButton
        function InverseKinematicsusingJacobianButtonPushed(app, event)
            InverseKinematicsusingjacobian
        end

        % Button pushed function: DynamicsequationButton
        function DynamicsequationButtonPushed(app, event)
            dynamics21
        end

        % Button pushed function: GuidetoAccessModuleButton
        function GuidetoAccessModuleButtonPushed(app, event)
           open MAE547_Final_Project_Submission_SASPS_Report.pdf
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RoboticsToolboxPanel = uipanel(app.UIFigure);
            app.RoboticsToolboxPanel.Title = 'Robotics Toolbox';
            app.FeaturesPanel = uipanel(app.RoboticsToolboxPanel);
            app.FeaturesPanel.Title = 'Features';
            app.HomogeneousTransformationMatrixButton_2 = uibutton(app.FeaturesPanel, 'push');
            app.HomogeneousTransformationMatrixButton_2.ButtonPushedFcn = createCallbackFcn(app, @HomogeneousTransformationMatrixButton_2Pushed, true);
            app.HomogeneousTransformationMatrixButton_2.Text = 'Homogeneous Transformation Matrix';
            app.TransformationMappingButton_2 = uibutton(app.FeaturesPanel, 'push');
            app.TransformationMappingButton_2.ButtonPushedFcn = createCallbackFcn(app, @TransformationMappingButton_2Pushed, true);
            app.TransformationMappingButton_2.Text = 'Transformation Mapping';
            app.RotationMatrixButton = uibutton(app.FeaturesPanel, 'push');
            app.RotationMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @RotationMatrixButtonPushed, true);
            app.RotationMatrixButton.Text = 'Rotation Matrix';
            app.ForwardKinematicsButton = uibutton(app.FeaturesPanel, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Forward Kinematics';
            app.WorkspaceButton = uibutton(app.FeaturesPanel, 'push');
            app.WorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @WorkspaceButtonPushed, true);
            app.WorkspaceButton.Text = 'Workspace';
            app.InverseKinematicsButton = uibutton(app.FeaturesPanel, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.DifferentialKinematicsGeometricJacobianButton = uibutton(app.FeaturesPanel, 'push');
            app.DifferentialKinematicsGeometricJacobianButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentialKinematicsGeometricJacobianButtonPushed, true);
            app.DifferentialKinematicsGeometricJacobianButton.Text = 'Differential Kinematics (Geometric Jacobian)';
            app.InverseDifferentialKinematicsButton = uibutton(app.FeaturesPanel, 'push');
            app.InverseDifferentialKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseDifferentialKinematicsButtonPushed, true);
            app.InverseDifferentialKinematicsButton.Text = 'Inverse Differential Kinematics';
            app.ManipulatorDynamicsButton = uibutton(app.FeaturesPanel, 'push');
            app.ManipulatorDynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @ManipulatorDynamicsButtonPushed, true);
            app.ManipulatorDynamicsButton.Text = 'Manipulator Dynamics';
            app.EulerAnglesRotationtoEulerButton = uibutton(app.FeaturesPanel, 'push');
            app.EulerAnglesRotationtoEulerButton.ButtonPushedFcn = createCallbackFcn(app, @EulerAnglesRotationtoEulerButtonPushed, true);
            app.EulerAnglesRotationtoEulerButton.Text = 'Euler Angles (Rotation to Euler)';
            app.DifferentialKinematicsNumericJacobianButton = uibutton(app.FeaturesPanel, 'push');
            app.DifferentialKinematicsNumericJacobianButton.ButtonPushedFcn = createCallbackFcn(app, @DifferentialKinematicsNumericJacobianButtonPushed, true);
            app.DifferentialKinematicsNumericJacobianButton.Text = 'Differential Kinematics (Numeric Jacobian)';
            app.Label = uilabel(app.FeaturesPanel);
            app.Label.Text = '';
            app.CalculatesthepositionofavectorwrtthebaseframeandplotsthemLabel = uilabel(app.FeaturesPanel);
            app.CalculatesthepositionofavectorwrtthebaseframeandplotsthemLabel.Text = 'Calculates the position of a vector wrt the base frame and plots them';
            app.GeneratesarotationmatrixfromEuleranglesandplotsthemLabel = uilabel(app.FeaturesPanel);
            app.GeneratesarotationmatrixfromEuleranglesandplotsthemLabel.Text = 'Generates a rotation matrix from Euler angles and plots them';
            app.CalculatesEuleranglesfromarotationmatrixLabel = uilabel(app.FeaturesPanel);
            app.CalculatesEuleranglesfromarotationmatrixLabel.Text = 'Calculates Euler angles from a rotation matrix';
            app.CalculatesendeffectorposewrtjointvariablesandplotsthemLabel = uilabel(app.FeaturesPanel);
            app.CalculatesendeffectorposewrtjointvariablesandplotsthemLabel.Text = 'Calculates end effector pose wrt joint variables and plots them';
            app.PlotsoperationalworkspaceoftherobotLabel = uilabel(app.FeaturesPanel);
            app.PlotsoperationalworkspaceoftherobotLabel.Text = 'Plots operational workspace of the robot';
            app.Label7 = uilabel(app.FeaturesPanel);
            app.Label7.Text = 'Calculates joint variables wrt end effector position and orientation';
            app.ThisgivesyouageometricjacobianwhichhasvariablesasaninputLabel = uilabel(app.FeaturesPanel);
            app.ThisgivesyouageometricjacobianwhichhasvariablesasaninputLabel.Text = 'This gives you a geometric Jacobian when input DH parameters are variables';
            app.Label9 = uilabel(app.FeaturesPanel);
            app.Label9.Text = 'This given you the geometricJaconbian when input DH parameters are numbers';
            app.GivesjointvelocitiesgiventheendeffectorvelocitiesLabel = uilabel(app.FeaturesPanel);
            app.GivesjointvelocitiesgiventheendeffectorvelocitiesLabel.Text = 'Gives joint velocities given the endeffector velocities';
            app.ThisgivesjointvariablesusingJacobianmatrixLabel = uilabel(app.FeaturesPanel);
            app.ThisgivesjointvariablesusingJacobianmatrixLabel.Text = 'This gives joint variables using Jacobian matrix ';
            app.ThisgivesplotofjointsandjointvelocitieswrttimeLabel = uilabel(app.FeaturesPanel);
            app.ThisgivesplotofjointsandjointvelocitieswrttimeLabel.Text = 'This gives plot of joints and joint velocities wrt time';
            app.Label13 = uilabel(app.FeaturesPanel);
            app.Label13.Text = 'Calulates a homogenous transformation matrix with rotation matrix and translation vector as input';
            app.InverseKinematicsusingJacobianButton = uibutton(app.FeaturesPanel, 'push');
            app.InverseKinematicsusingJacobianButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsusingJacobianButtonPushed, true);
            app.InverseKinematicsusingJacobianButton.Text = 'Inverse Kinematics using Jacobian';
            app.DynamicsequationButton = uibutton(app.FeaturesPanel, 'push');
            app.DynamicsequationButton.ButtonPushedFcn = createCallbackFcn(app, @DynamicsequationButtonPushed, true);
            app.DynamicsequationButton.Text = 'Dynamics equation';
            app.GivesthedynamicsequationfortorqueLabel = uilabel(app.FeaturesPanel);
            app.GivesthedynamicsequationfortorqueLabel.Text = 'Gives the dynamics equation for torque';
            app.GuidetoAccessModuleButton = uibutton(app.RoboticsToolboxPanel, 'push');
            app.GuidetoAccessModuleButton.ButtonPushedFcn = createCallbackFcn(app, @GuidetoAccessModuleButtonPushed, true);
            app.GuidetoAccessModuleButton.Text = 'Guide to Access Module';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HomePage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end