classdef Inverse_kin_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputvariablesPanel            matlab.ui.container.Panel
        PxEditFieldLabel               matlab.ui.control.Label
        PxEditField                    matlab.ui.control.NumericEditField
        PyEditFieldLabel               matlab.ui.control.Label
        PyEditField                    matlab.ui.control.NumericEditField
        PzEditFieldLabel               matlab.ui.control.Label
        PzEditField                    matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        OutputPanel                    matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        WorkspacePlotAxesPanel         matlab.ui.container.Panel
        XminEditFieldLabel             matlab.ui.control.Label
        XminEditField                  matlab.ui.control.NumericEditField
        XmaxEditFieldLabel             matlab.ui.control.Label
        XmaxEditField                  matlab.ui.control.NumericEditField
        YminEditFieldLabel             matlab.ui.control.Label
        YminEditField                  matlab.ui.control.NumericEditField
        YmaxEditFieldLabel             matlab.ui.control.Label
        YmaxEditField                  matlab.ui.control.NumericEditField
        ZmaxEditFieldLabel             matlab.ui.control.Label
        ZmaxEditField                  matlab.ui.control.NumericEditField
        ZminEditFieldLabel             matlab.ui.control.Label
        ZminEditField                  matlab.ui.control.NumericEditField
        DHparametersandjointtypePanel  matlab.ui.container.Panel
        NumberofLinksEditFieldLabel    matlab.ui.control.Label
        NumberofLinksEditField         matlab.ui.control.NumericEditField
        ThetaEditFieldLabel            matlab.ui.control.Label
        ThetaEditField                 matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        dEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        alphaEditFieldLabel            matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.NumericEditField
        LinkEditFieldLabel             matlab.ui.control.Label
        LinkEditField                  matlab.ui.control.NumericEditField
        FillRowButton                  matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        UITable3                       matlab.ui.control.Table
        JointTypeEditFieldLabel        matlab.ui.control.Label
        JointTypeEditField             matlab.ui.control.NumericEditField
        forrevoluteand1forprismaticLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            dh=app.UITable2.Data
          assignin('base','dh',dh)
          type_joint=app.UITable3.Data'
          assignin('base','type_joint',type_joint)
          Workspace=[app.XminEditField.Value app.XmaxEditField.Value app.YminEditField.Value app.YmaxEditField.Value app.ZminEditField.Value app.ZmaxEditField.Value]
          assignin('base','Workspace',Workspace)
          P=[app.PxEditField.Value,app.PyEditField.Value,app.PzEditField.Value];
          assignin('base','P',P)
          for i=1:length(type_joint)
        if type_joint(1,i)==0
        L(i)=Link([dh(i,1),dh(i,2),dh(i,3),dh(i,4)]);
        else
        L(i)=Link([dh(i,1),dh(i,2),dh(i,3),dh(i,4),1]);
        end
    end
R = SerialLink(L);
if length(type_joint)>=6
T=eye(4);
T(1:3,4)=P;
q =R.ikine(T);
R.plot(q,'workspace',Workspace)
end
if length(type_joint)<6
T=eye(4);
T(1:3,4)=P;
q=R.ikunc(T);
R.fkine(q);
%q=R.ikine(T,'q0',q0,'mask',m)*(180/pi);
    R.plot(q,'workspace',Workspace)

end 
for i=1:length(type_joint)
     if type_joint(i)==0
        q(:,i)=q(:,i)*pi/180;
     end
     app.UITable.Data=q'
               assignin('base','q',app.UITable.Data)

 end
        end

        % Button pushed function: FillRowButton
        function FillRowButtonPushed(app, event)
            app.UITable2.Data(app.LinkEditField.Value,:) = [app.ThetaEditField.Value,app.dEditField.Value,app.aEditField.Value,app.alphaEditField.Value]
            app.UITable3.Data(app.LinkEditField.Value,1) = [app.JointTypeEditField.Value]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputvariablesPanel = uipanel(app.UIFigure);
            app.InputvariablesPanel.Title = 'Input variables';
            app.PxEditFieldLabel = uilabel(app.InputvariablesPanel);
            app.PxEditFieldLabel.Text = 'Px';
            app.PxEditField = uieditfield(app.InputvariablesPanel, 'numeric');
            app.PyEditFieldLabel = uilabel(app.InputvariablesPanel);
            app.PyEditFieldLabel.Text = 'Py';
            app.PyEditField = uieditfield(app.InputvariablesPanel, 'numeric');
            app.PzEditFieldLabel = uilabel(app.InputvariablesPanel);
            app.PzEditFieldLabel.Text = 'Pz';
            app.PzEditField = uieditfield(app.InputvariablesPanel, 'numeric');
            app.RunButton = uibutton(app.InputvariablesPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.UITable = uitable(app.OutputPanel);
            app.UITable.ColumnName = {'q (1 : n)'};
            app.UITable.RowName = {};
            app.WorkspacePlotAxesPanel = uipanel(app.UIFigure);
            app.WorkspacePlotAxesPanel.Title = 'Workspace (Plot Axes)';
            app.XminEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.XminEditFieldLabel.Text = 'X min';
            app.XminEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.XmaxEditFieldLabel.Text = 'X max';
            app.XmaxEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.YminEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.YminEditFieldLabel.Text = 'Y min';
            app.YminEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.YmaxEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.ZmaxEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.ZmaxEditFieldLabel.Text = 'Z max';
            app.ZmaxEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.ZminEditFieldLabel = uilabel(app.WorkspacePlotAxesPanel);
            app.ZminEditFieldLabel.Text = 'Z min';
            app.ZminEditField = uieditfield(app.WorkspacePlotAxesPanel, 'numeric');
            app.DHparametersandjointtypePanel = uipanel(app.UIFigure);
            app.DHparametersandjointtypePanel.Title = 'DH parameters and joint type';
            app.NumberofLinksEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.NumberofLinksEditFieldLabel.Text = 'Number of Links';
            app.NumberofLinksEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.dEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.aEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.LinkEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.LinkEditFieldLabel.Text = 'Link';
            app.LinkEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.FillRowButton = uibutton(app.DHparametersandjointtypePanel, 'push');
            app.FillRowButton.ButtonPushedFcn = createCallbackFcn(app, @FillRowButtonPushed, true);
            app.FillRowButton.Text = 'Fill Row';
            app.UITable2 = uitable(app.DHparametersandjointtypePanel);
            app.UITable2.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.DHparametersandjointtypePanel);
            app.UITable3.ColumnName = {'joint type'};
            app.UITable3.RowName = {};
            app.JointTypeEditFieldLabel = uilabel(app.DHparametersandjointtypePanel);
            app.JointTypeEditFieldLabel.Text = 'Joint Type';
            app.JointTypeEditField = uieditfield(app.DHparametersandjointtypePanel, 'numeric');
            app.forrevoluteand1forprismaticLabel = uilabel(app.DHparametersandjointtypePanel);
            app.forrevoluteand1forprismaticLabel.Text = '0 for revolute and 1 for prismatic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inverse_kin_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end