classdef Rotation_Matrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RotationMatrixPanel            matlab.ui.container.Panel
        InputsPanel                    matlab.ui.container.Panel
        OrderofTransformationDropDownLabel  matlab.ui.control.Label
        OrderofTransformationDropDown  matlab.ui.control.DropDown
        Angle1EditFieldLabel           matlab.ui.control.Label
        Angle1EditField                matlab.ui.control.NumericEditField
        Angle2EditFieldLabel           matlab.ui.control.Label
        Angle2EditField                matlab.ui.control.NumericEditField
        Angle3EditFieldLabel           matlab.ui.control.Label
        Angle3EditField                matlab.ui.control.NumericEditField
        TransformationTypeSwitchLabel  matlab.ui.control.Label
        TransformationTypeSwitch       matlab.ui.control.Switch
        ExecuteButton                  matlab.ui.control.Button
        CCurrentFrameFFixedFrameLabel  matlab.ui.control.Label
        MatrixPanel                    matlab.ui.container.Panel
        EditField                      matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        NoteEditFieldLabel             matlab.ui.control.Label
        NoteEditField                  matlab.ui.control.EditField
        EditField_9                    matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
%Ask for either of 12 Euler angle transitions
 %disp("These are: XYZ,XZY,XYX,XZX,YXZ,YZX,YXY,YZY,ZXY,ZYX,ZXZ,ZYZ.");
 
 %order = input('Enter either of 12 Euler angles possibilities: ','s'); 
 order = app.OrderofTransformationDropDown.Value;
 phi = app.Angle1EditField.Value; phi = phi*pi/180;
 theta = app.Angle2EditField.Value; theta = theta*pi/180;
 psi = app.Angle3EditField.Value; psi = psi*pi/180;
 transformation_type = app.TransformationTypeSwitch.Value;
 %C = "Current"; F = "Fixed"; r = zeros(3);
 
 %phi = input('Enter first angle: '); phi = phi*pi/180;
 %theta = input('Enter second angle: '); theta = theta*pi/180;
 %psi = input('Enter third angle: '); psi = psi*pi/180;
 
     switch order
         case 'XYZ'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         case 'XZY'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'XYX'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [1 0 0;0 cos(psi) -sin(psi);0 sin(psi) cos(psi)];
         case 'XZX'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [1 0 0;0 cos(psi) -sin(psi);0 sin(psi) cos(psi)];
         case 'YXZ'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         case 'YZX'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [1 0 0;0 cos(psi) -sin(psi);0 sin(psi) cos(psi)];
         case 'YXY'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'YZY'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'ZXY'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'ZYX'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [1 0 0;0 cos(psi) -sin(psi); 0 sin(psi) cos(psi)];
         case 'ZXZ'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         case 'ZYZ'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         otherwise 
             disp('Unrecognized Transformation!')
     end
     
%transformation_type = input("Enter 'C' or 'F' for transformations: ",'s');    
        if transformation_type == 'C' %Choice for Current frame transformation (post-multiplication)
             r = R_1*R_2*R_3;
             
        elseif transformation_type == 'F' %Choice for fixed frame transformation (pre-multiplication)        
             r = R_3*R_2*R_1;
        end
app.EditField.Value = r(1,1);
app.EditField_2.Value = r(1,2);
app.EditField_3.Value = r(1,3);
app.EditField_4.Value = r(2,1);
app.EditField_5.Value = r(2,2);
app.EditField_6.Value = r(2,3);
app.EditField_7.Value = r(3,1);
app.EditField_8.Value = r(3,2);
app.EditField_9.Value = r(3,3);

figure
trplot(eye(3))
hold on
tranimate(eye(3),r,'fps',100,'nsteps',1000,'rgb')
%trplot(r,'rgb')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RotationMatrixPanel = uipanel(app.UIFigure);
            app.RotationMatrixPanel.Title = 'Rotation Matrix';
            app.InputsPanel = uipanel(app.RotationMatrixPanel);
            app.InputsPanel.Title = 'Inputs';
            app.OrderofTransformationDropDownLabel = uilabel(app.InputsPanel);
            app.OrderofTransformationDropDownLabel.Text = 'Order of Transformation';
            app.OrderofTransformationDropDown = uidropdown(app.InputsPanel);
            app.OrderofTransformationDropDown.Items = {'XYZ', 'XZY', 'XYX', 'XZX', 'YXZ', 'YZX', 'YXY', 'YZY', 'ZXY', 'ZYX', 'ZXZ', 'ZYZ'};
            app.OrderofTransformationDropDown.Value = 'XYZ';
            app.Angle1EditFieldLabel = uilabel(app.InputsPanel);
            app.Angle1EditFieldLabel.Text = 'Angle 1';
            app.Angle1EditField = uieditfield(app.InputsPanel, 'numeric');
            app.Angle2EditFieldLabel = uilabel(app.InputsPanel);
            app.Angle2EditFieldLabel.Text = 'Angle 2';
            app.Angle2EditField = uieditfield(app.InputsPanel, 'numeric');
            app.Angle3EditFieldLabel = uilabel(app.InputsPanel);
            app.Angle3EditFieldLabel.Text = 'Angle 3';
            app.Angle3EditField = uieditfield(app.InputsPanel, 'numeric');
            app.TransformationTypeSwitchLabel = uilabel(app.InputsPanel);
            app.TransformationTypeSwitchLabel.Text = 'Transformation Type';
            app.TransformationTypeSwitch = uiswitch(app.InputsPanel, 'slider');
            app.TransformationTypeSwitch.Items = {'C', 'F'};
            app.TransformationTypeSwitch.Value = 'C';
            app.ExecuteButton = uibutton(app.InputsPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.CCurrentFrameFFixedFrameLabel = uilabel(app.InputsPanel);
            app.CCurrentFrameFFixedFrameLabel.Text = ' C : Current Frame | F : Fixed Frame';
            app.MatrixPanel = uipanel(app.RotationMatrixPanel);
            app.MatrixPanel.Title = 'Matrix';
            app.EditField = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField_4 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_2 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_5 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EditField_7 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_8.Editable = 'off';
            app.EditField_3 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_6 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_6.Editable = 'off';
            app.EditField_9 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField_9.Editable = 'off';
            app.NoteEditFieldLabel = uilabel(app.RotationMatrixPanel);
            app.NoteEditFieldLabel.Text = 'Note:';
            app.NoteEditField = uieditfield(app.RotationMatrixPanel, 'text');
            app.NoteEditField.Editable = 'off';
            app.NoteEditField.Value = 'The plot will open in a seperate window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rotation_Matrix

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end