classdef Transformation_Mapping_UPDATED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TransformationMappingPanel     matlab.ui.container.Panel
        InputsPanel                    matlab.ui.container.Panel
        PositionVectorsPanel           matlab.ui.container.Panel
        DefinePositionofFrameBinFrameALabel  matlab.ui.control.Label
        DefinePositionofVectorinFrameBLabel  matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YLabel                         matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        XEditField_2Label              matlab.ui.control.Label
        XEditField_2                   matlab.ui.control.NumericEditField
        YEditField_4Label              matlab.ui.control.Label
        YEditField_4                   matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        ZEditField_2                   matlab.ui.control.NumericEditField
        ExecuteButton                  matlab.ui.control.Button
        OrderofTransformationDropDownLabel  matlab.ui.control.Label
        OrderofTransformationDropDown  matlab.ui.control.DropDown
        Angle1EditFieldLabel           matlab.ui.control.Label
        Angle1EditField                matlab.ui.control.NumericEditField
        Angle2EditFieldLabel           matlab.ui.control.Label
        Angle2EditField                matlab.ui.control.NumericEditField
        Angle3EditFieldLabel           matlab.ui.control.Label
        Angle3EditField                matlab.ui.control.NumericEditField
        TransformationTypeSwitchLabel  matlab.ui.control.Label
        TransformationTypeSwitch       matlab.ui.control.Switch
        CCurrentFrameFFixedFrameLabel  matlab.ui.control.Label
        PositionofVectorinFrameAPanel  matlab.ui.container.Panel
        EditField                      matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        XLabel_2                       matlab.ui.control.Label
        YLabel_2                       matlab.ui.control.Label
        ZLabel                         matlab.ui.control.Label
        NoteTextAreaLabel              matlab.ui.control.Label
        NoteTextArea                   matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
 %Ask for either of 12 Euler angle transitions
%disp("These are: XYZ,XZY,XYX,XZX,YXZ,YZX,YXY,YZY,ZXY,ZYX,ZXZ,ZYZ.");

%PAB = zeros(3,1); PB = zeros(3,1);
order = app.OrderofTransformationDropDown.Value;
phi = app.Angle1EditField.Value; phi = phi*pi/180;
theta = app.Angle2EditField.Value; theta = theta*pi/180;
psi = app.Angle3EditField.Value; psi = psi*pi/180;
PAB(1,1) = app.XEditField.Value;
PAB(2,1) = app.YEditField.Value;
PAB(3,1) = app.ZEditField.Value;
PB(1,1) = app.XEditField_2.Value;
PB(2,1) = app.YEditField_4.Value;
PB(3,1) = app.ZEditField_2.Value;
transformation_type = app.TransformationTypeSwitch.Value;

 %order = input('Enter either of 12 Euler angles possibilities: ','s'); 
 %phi = input('Enter first angle: '); phi = phi*pi/180;
 %theta = input('Enter second angle: '); theta = theta*pi/180;
 %psi = input('Enter third angle: '); psi = psi*pi/180;
 %PAB = zeros(3,1); PB = zeros(3,1); 
 %disp("Enter corresponding PAB values: ") %Position Vector of B in A 
 %PAB(1,1) = input("What is your translation along x-axis?");
 %PAB(2,1) = input("What is your translation along y-axis?");
 %PAB(3,1) = input("What is your translation along z-axis?");
 %disp("Enter corresponding PB values: ") % Position Vector of B 
 %PB(1,1) = input("What is your translation along x-axis?");
 %PB(2,1) = input("What is your translation along y-axis?");
 %PB(3,1) = input("What is your translation along z-axis?");
 
 PBtilda = vertcat(PB,1);
     switch order
         case 'XYZ'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         case 'XZY'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'XYX'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [1 0 0;0 cos(psi) -sin(psi);0 sin(psi) cos(psi)];
         case 'XZX'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [1 0 0;0 cos(psi) -sin(psi);0 sin(psi) cos(psi)];
         case 'YXZ'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         case 'YZX'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [1 0 0;0 cos(psi) -sin(psi);0 sin(psi) cos(psi)];
         case 'YXY'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'YZY'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'ZXY'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'ZYX'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [1 0 0;0 cos(psi) -sin(psi); 0 sin(psi) cos(psi)];
         case 'ZXZ'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         case 'ZYZ'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         otherwise 
             disp('Unrecognized Transformation!')
     end
     
%transformation_type = input("Enter 'C' or 'F' for transformations: ",'s');
      
        if transformation_type == 'C' %Choice for Current frame transformation (post-multiplication)
             Rot_mat = R_1*R_2*R_3;
             
                elseif transformation_type == 'F' %Choice for fixed frame transformation (pre-multiplication)        
             Rot_mat = R_3*R_2*R_1;
        end
        
HMT = vertcat(horzcat(Rot_mat,PAB),[0 0 0 1]);
%tranimate(HMT, 'arrow','width', 0.5, 'text','rgb')

PAtilda = HMT*PBtilda %Display this as trans_mat output

app.EditField.Value = PAtilda(1,1);
app.EditField_2.Value = PAtilda(2,1);
app.EditField_3.Value = PAtilda(3,1);


%%%%%%%%%%%%%GRAPH STARTS HERE%%%%%%%%%%%%%%%%%
starting_axis=eye(3);
end_axis=starting_axis*Rot_mat;

for i=1:3
    quiver3(0,0,0,starting_axis(i,1),starting_axis(i,2),starting_axis(i,3),0);
    
hold on
quiver3(PAB(1),PAB(2),PAB(3),end_axis(i,1),end_axis(i,2),end_axis(i,3),0);
end

view([-19.80 34.80])
hold on
quiver3(0,0,0,PAB(1),PAB(2),PAB(3),0)
axis equal
%quiver3(PAB(1),PAB(2),PAB(3),PAtilda(1),PAtilda(2),PAtilda(3))
plot3([PAB(1) PAtilda(1)], [PAB(2) PAtilda(2)], [PAB(3) PAtilda(3)],'k--');
plot3([0 PAtilda(1)], [0 PAtilda(2)], [0 PAtilda(3)],'m--');
axis vis3d

%axis([-2   -2  -2  ])
xlabel('X Axis')
ylabel('Y Axis')

legend('X_A','X_B','Y_A','Y_B','Z_A','Z_B','P^A_B','P_B','P_A','show','Location','northeastoutside')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TransformationMappingPanel = uipanel(app.UIFigure);
            app.TransformationMappingPanel.Title = 'Transformation Mapping';
            app.InputsPanel = uipanel(app.TransformationMappingPanel);
            app.InputsPanel.Title = 'Inputs';
            app.PositionVectorsPanel = uipanel(app.InputsPanel);
            app.PositionVectorsPanel.Title = 'Position Vectors';
            app.DefinePositionofFrameBinFrameALabel = uilabel(app.PositionVectorsPanel);
            app.DefinePositionofFrameBinFrameALabel.Text = 'Define Position of Frame B in Frame A';
            app.DefinePositionofVectorinFrameBLabel = uilabel(app.PositionVectorsPanel);
            app.DefinePositionofVectorinFrameBLabel.Text = 'Define Position of  Vector in Frame B';
            app.XLabel = uilabel(app.PositionVectorsPanel);
            app.XLabel.Text = 'X :';
            app.XEditField = uieditfield(app.PositionVectorsPanel, 'numeric');
            app.YLabel = uilabel(app.PositionVectorsPanel);
            app.YLabel.Text = 'Y :';
            app.YEditField = uieditfield(app.PositionVectorsPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.PositionVectorsPanel);
            app.ZEditFieldLabel.Text = 'Z :';
            app.ZEditField = uieditfield(app.PositionVectorsPanel, 'numeric');
            app.XEditField_2Label = uilabel(app.PositionVectorsPanel);
            app.XEditField_2Label.Text = 'X :';
            app.XEditField_2 = uieditfield(app.PositionVectorsPanel, 'numeric');
            app.YEditField_4Label = uilabel(app.PositionVectorsPanel);
            app.YEditField_4Label.Text = 'Y :';
            app.YEditField_4 = uieditfield(app.PositionVectorsPanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.PositionVectorsPanel);
            app.ZEditField_2Label.Text = 'Z :';
            app.ZEditField_2 = uieditfield(app.PositionVectorsPanel, 'numeric');
            app.ExecuteButton = uibutton(app.PositionVectorsPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.OrderofTransformationDropDownLabel = uilabel(app.InputsPanel);
            app.OrderofTransformationDropDownLabel.Text = 'Order of Transformation';
            app.OrderofTransformationDropDown = uidropdown(app.InputsPanel);
            app.OrderofTransformationDropDown.Items = {'XYZ', 'XZY', 'XYX', 'XZX', 'YXZ', 'YZX', 'YXY', 'YZY', 'ZXY', 'ZYX', 'ZXZ', 'ZYZ'};
            app.OrderofTransformationDropDown.Value = 'XYZ';
            app.Angle1EditFieldLabel = uilabel(app.InputsPanel);
            app.Angle1EditFieldLabel.Text = 'Angle 1';
            app.Angle1EditField = uieditfield(app.InputsPanel, 'numeric');
            app.Angle2EditFieldLabel = uilabel(app.InputsPanel);
            app.Angle2EditFieldLabel.Text = 'Angle 2';
            app.Angle2EditField = uieditfield(app.InputsPanel, 'numeric');
            app.Angle3EditFieldLabel = uilabel(app.InputsPanel);
            app.Angle3EditFieldLabel.Text = 'Angle 3';
            app.Angle3EditField = uieditfield(app.InputsPanel, 'numeric');
            app.TransformationTypeSwitchLabel = uilabel(app.InputsPanel);
            app.TransformationTypeSwitchLabel.Text = 'Transformation Type';
            app.TransformationTypeSwitch = uiswitch(app.InputsPanel, 'slider');
            app.TransformationTypeSwitch.Items = {'C', 'F'};
            app.TransformationTypeSwitch.Value = 'C';
            app.CCurrentFrameFFixedFrameLabel = uilabel(app.InputsPanel);
            app.CCurrentFrameFFixedFrameLabel.Text = 'C : Current Frame | F : Fixed Frame';
            app.PositionofVectorinFrameAPanel = uipanel(app.TransformationMappingPanel);
            app.PositionofVectorinFrameAPanel.Title = 'Position of Vector in Frame A';
            app.EditField = uieditfield(app.PositionofVectorinFrameAPanel, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.PositionofVectorinFrameAPanel, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.PositionofVectorinFrameAPanel, 'numeric');
            app.EditField_3.Editable = 'off';
            app.XLabel_2 = uilabel(app.PositionofVectorinFrameAPanel);
            app.XLabel_2.Text = 'X : ';
            app.YLabel_2 = uilabel(app.PositionofVectorinFrameAPanel);
            app.YLabel_2.Text = 'Y : ';
            app.ZLabel = uilabel(app.PositionofVectorinFrameAPanel);
            app.ZLabel.Text = 'Z : ';
            app.NoteTextAreaLabel = uilabel(app.TransformationMappingPanel);
            app.NoteTextAreaLabel.Text = 'Note : ';
            app.NoteTextArea = uitextarea(app.TransformationMappingPanel);
            app.NoteTextArea.Editable = 'off';
            app.NoteTextArea.Value = {'The corresponding Transformation Mapping will be appear in a seperate figure window.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transformation_Mapping_UPDATED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end