classdef Transformation_Matrix_UPDATED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TransformationMatrixPanel      matlab.ui.container.Panel
        InputsPanel                    matlab.ui.container.Panel
        OrderofTransformationDropDownLabel  matlab.ui.control.Label
        OrderofTransformationDropDown  matlab.ui.control.DropDown
        Angle2EditFieldLabel           matlab.ui.control.Label
        Angle2EditField                matlab.ui.control.NumericEditField
        Angle3EditFieldLabel           matlab.ui.control.Label
        Angle3EditField                matlab.ui.control.NumericEditField
        Angle1EditFieldLabel           matlab.ui.control.Label
        Angle1EditField                matlab.ui.control.NumericEditField
        TransformationTypeSwitchLabel  matlab.ui.control.Label
        TransformationTypeSwitch       matlab.ui.control.Switch
        CCurrentFrameFFixedFrameLabel  matlab.ui.control.Label
        ExecuteButton                  matlab.ui.control.Button
        DefinePositionofFrameBinFrameALabel  matlab.ui.control.Label
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        MatrixPanel                    matlab.ui.container.Panel
        XEditField_2                   matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        ZEditField_2                   matlab.ui.control.NumericEditField
        ZEditField_3                   matlab.ui.control.NumericEditField
        ZEditField_4                   matlab.ui.control.NumericEditField
        ZEditField_5                   matlab.ui.control.NumericEditField
        ZEditField_6                   matlab.ui.control.NumericEditField
        ZEditField_7                   matlab.ui.control.NumericEditField
        ZEditField_8                   matlab.ui.control.NumericEditField
        ZEditField_9                   matlab.ui.control.NumericEditField
        ZEditField_10                  matlab.ui.control.NumericEditField
        ZEditField_11                  matlab.ui.control.NumericEditField
        ZEditField_12                  matlab.ui.control.NumericEditField
        ZEditField_13                  matlab.ui.control.NumericEditField
        ZEditField_14                  matlab.ui.control.NumericEditField
        ZEditField_15                  matlab.ui.control.NumericEditField
        NoteEditFieldLabel             matlab.ui.control.Label
        NoteEditField                  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
%Ask for either of 12 Euler angle transitions
%disp("These are: XYZ,XZY,XYX,XZX,YXZ,YZX,YXY,YZY,ZXY,ZYX,ZXZ,ZYZ.");

 order = app.OrderofTransformationDropDown.Value;
 phi = app.Angle1EditField.Value; phi = phi*pi/180;
 theta = app.Angle2EditField.Value; theta = theta*pi/180;
 psi = app.Angle3EditField.Value; psi = psi*pi/180;
 PAB(1,1) = app.XEditField.Value;
 PAB(2,1) = app.YEditField.Value;
 PAB(3,1) = app.ZEditField.Value;
 transformation_type = app.TransformationTypeSwitch.Value;
 
 
 %order = input('Enter either of 12 Euler angles possibilities: ','s'); 
 %phi = input('Enter first angle: '); phi = phi*pi/180;
 %theta = input('Enter second angle: '); theta = theta*pi/180;
 %psi = input('Enter third angle: '); psi = psi*pi/180;
 %PAB = zeros(3,1);
 %disp("Enter corresponding PAB values: ") %Position Vector of Frame B in Frame A 
 %PAB(1,1) = input("What is your translation along x-axis?");
 %PAB(2,1) = input("What is your translation along y-axis?");
 %PAB(3,1) = input("What is your translation along z-axis?");
 
     switch order
         case 'XYZ'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         case 'XZY'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'XYX'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [1 0 0;0 cos(psi) -sin(psi);0 sin(psi) cos(psi)];
         case 'XZX'
             R_1 = [1 0 0;0 cos(phi) -sin(phi);0 sin(phi) cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [1 0 0;0 cos(psi) -sin(psi);0 sin(psi) cos(psi)];
         case 'YXZ'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         case 'YZX'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [1 0 0;0 cos(psi) -sin(psi);0 sin(psi) cos(psi)];
         case 'YXY'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'YZY'
             R_1 = [cos(phi) 0 sin(phi);0 1 0;-sin(phi) 0 cos(phi)];
             R_2 = [cos(theta) -sin(theta) 0;sin(theta) cos(theta) 0;0 0 1];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'ZXY'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) 0 sin(psi);0 1 0;-sin(psi) 0 cos(psi)];
         case 'ZYX'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [1 0 0;0 cos(psi) -sin(psi); 0 sin(psi) cos(psi)];
         case 'ZXZ'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [1 0 0;0 cos(theta) -sin(theta);0 sin(theta) cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         case 'ZYZ'
             R_1 = [cos(phi) -sin(phi) 0;sin(phi) cos(phi) 0;0 0 1];
             R_2 = [cos(theta) 0 sin(theta);0 1 0;-sin(theta) 0 cos(theta)];
             R_3 = [cos(psi) -sin(psi) 0; sin(psi) cos(psi) 0;0 0 1];
         otherwise 
             disp('Unrecognized Transformation!')
     end
     
%transformation_type = input("Enter 'C' or 'F' for transformations: ",'s');
      
        if transformation_type == 'C' %Choice for Current frame transformation (post-multiplication)
             Rot_mat = R_1*R_2*R_3;
             
                elseif transformation_type == 'F' %Choice for fixed frame transformation (pre-multiplication)        
             Rot_mat = R_3*R_2*R_1;
        end
        
HMT = vertcat(horzcat(Rot_mat,PAB),[0 0 0 1]);

app.XEditField_2.Value = HMT(1,1);
app.EditField.Value = HMT(1,2);
app.ZEditField_2.Value = HMT(1,3);
app.ZEditField_3.Value = HMT(1,4);
app.ZEditField_4.Value = HMT(2,1);
app.ZEditField_5.Value = HMT(2,2);
app.ZEditField_6.Value = HMT(2,3);
app.ZEditField_7.Value = HMT(2,4);
app.ZEditField_8.Value = HMT(3,1);
app.ZEditField_9.Value = HMT(3,2);
app.ZEditField_10.Value = HMT(3,3);
app.ZEditField_11.Value = HMT(3,4);
app.ZEditField_12.Value = HMT(4,1);
app.ZEditField_13.Value = HMT(4,2);
app.ZEditField_14.Value = HMT(4,3);
app.ZEditField_15.Value = HMT(4,4);


%%%%% GRAPH %%%%%%%%%%%%

starting_axis=eye(3);
end_axis=starting_axis*Rot_mat;

for i=1:3
    quiver3(0,0,0,starting_axis(i,1),starting_axis(i,2),starting_axis(i,3),0);
    
hold on
quiver3(PAB(1),PAB(2),PAB(3),end_axis(i,1),end_axis(i,2),end_axis(i,3),0);
end

view([-19.80 34.80])
hold on
quiver3(0,0,0,PAB(1),PAB(2),PAB(3),0,'k')
axis equal
% %quiver3(PAB(1),PAB(2),PAB(3),PAtilda(1),PAtilda(2),PAtilda(3))
% plot3([PAB(1) PAtilda(1)], [PAB(2) PAtilda(2)], [PAB(3) PAtilda(3)],'k--');
% plot3([0 PAtilda(1)], [0 PAtilda(2)], [0 PAtilda(3)],'m--');

xlabel('X Axis')
ylabel('Y Axis')

legend('X_A','X_B','Y_A','Y_B','Z_A','Z_B','P^A_B','show','Location','northeastoutside')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TransformationMatrixPanel = uipanel(app.UIFigure);
            app.TransformationMatrixPanel.Title = 'Transformation Matrix';
            app.InputsPanel = uipanel(app.TransformationMatrixPanel);
            app.InputsPanel.Title = 'Inputs';
            app.OrderofTransformationDropDownLabel = uilabel(app.InputsPanel);
            app.OrderofTransformationDropDownLabel.Text = 'Order of Transformation';
            app.OrderofTransformationDropDown = uidropdown(app.InputsPanel);
            app.OrderofTransformationDropDown.Items = {'XYZ', 'XZY', 'XYX', 'XZX', 'YXZ', 'YZX', 'YXY', 'YZY', 'ZXY', 'ZYX', 'ZXZ', 'ZYZ'};
            app.OrderofTransformationDropDown.Value = 'XYZ';
            app.Angle2EditFieldLabel = uilabel(app.InputsPanel);
            app.Angle2EditFieldLabel.Text = 'Angle 2';
            app.Angle2EditField = uieditfield(app.InputsPanel, 'numeric');
            app.Angle3EditFieldLabel = uilabel(app.InputsPanel);
            app.Angle3EditFieldLabel.Text = 'Angle 3';
            app.Angle3EditField = uieditfield(app.InputsPanel, 'numeric');
            app.Angle1EditFieldLabel = uilabel(app.InputsPanel);
            app.Angle1EditFieldLabel.Text = 'Angle 1';
            app.Angle1EditField = uieditfield(app.InputsPanel, 'numeric');
            app.TransformationTypeSwitchLabel = uilabel(app.InputsPanel);
            app.TransformationTypeSwitchLabel.Text = 'Transformation Type';
            app.TransformationTypeSwitch = uiswitch(app.InputsPanel, 'slider');
            app.TransformationTypeSwitch.Items = {'C', 'F'};
            app.TransformationTypeSwitch.Value = 'C';
            app.CCurrentFrameFFixedFrameLabel = uilabel(app.InputsPanel);
            app.CCurrentFrameFFixedFrameLabel.Text = ' C : Current Frame | F : Fixed Frame';
            app.ExecuteButton = uibutton(app.InputsPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.DefinePositionofFrameBinFrameALabel = uilabel(app.InputsPanel);
            app.DefinePositionofFrameBinFrameALabel.Text = 'Define Position of Frame B in Frame A';
            app.XEditFieldLabel = uilabel(app.InputsPanel);
            app.XEditFieldLabel.Text = 'X :';
            app.XEditField = uieditfield(app.InputsPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.InputsPanel);
            app.YEditFieldLabel.Text = 'Y :';
            app.YEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.InputsPanel);
            app.ZEditFieldLabel.Text = 'Z :';
            app.ZEditField = uieditfield(app.InputsPanel, 'numeric');
            app.MatrixPanel = uipanel(app.TransformationMatrixPanel);
            app.MatrixPanel.Title = 'Matrix';
            app.XEditField_2 = uieditfield(app.MatrixPanel, 'numeric');
            app.EditField = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_2 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_3 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_4 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_5 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_6 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_7 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_8 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_9 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_10 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_11 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_12 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_13 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_14 = uieditfield(app.MatrixPanel, 'numeric');
            app.ZEditField_15 = uieditfield(app.MatrixPanel, 'numeric');
            app.NoteEditFieldLabel = uilabel(app.TransformationMatrixPanel);
            app.NoteEditFieldLabel.Text = 'Note:';
            app.NoteEditField = uieditfield(app.TransformationMatrixPanel, 'text');
            app.NoteEditField.Editable = 'off';
            app.NoteEditField.Value = 'The configuration plot will open in a seperate window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transformation_Matrix_UPDATED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end