classdef dynamics11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        UITable2                       matlab.ui.control.Table
        NoofLinksEditFieldLabel        matlab.ui.control.Label
        NoofLinksEditField             matlab.ui.control.NumericEditField
        thetaEditFieldLabel            matlab.ui.control.Label
        thetaEditField                 matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        dEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        Timeforsimulation0Label        matlab.ui.control.Label
        Timeforsimulation0EditField    matlab.ui.control.NumericEditField
        TorqueEditFieldLabel           matlab.ui.control.Label
        TorqueEditField                matlab.ui.control.NumericEditField
        alpharadiansEditFieldLabel     matlab.ui.control.Label
        alpharadiansEditField          matlab.ui.control.NumericEditField
        LinkNoEditFieldLabel           matlab.ui.control.Label
        LinkNoEditField                matlab.ui.control.NumericEditField
        FillRowButton                  matlab.ui.control.Button
        Jointangle0EditFieldLabel      matlab.ui.control.Label
        Jointangle0EditField           matlab.ui.control.NumericEditField
        JointDistanceEditFieldLabel    matlab.ui.control.Label
        JointDistanceEditField         matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        MassoflinkEditFieldLabel       matlab.ui.control.Label
        MassoflinkEditField            matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel        matlab.ui.control.Label
        GearRatioEditField             matlab.ui.control.NumericEditField
        InertiaofmassEditFieldLabel    matlab.ui.control.Label
        InertiaofmassEditField         matlab.ui.control.NumericEditField
        InertiaofLinkEditFieldLabel    matlab.ui.control.Label
        InertiaofLinkEditField         matlab.ui.control.NumericEditField
        LengthofCOGEditFieldLabel      matlab.ui.control.Label
        LengthofCOGEditField           matlab.ui.control.NumericEditField
        JointTypeEditFieldLabel        matlab.ui.control.Label
        JointTypeEditField             matlab.ui.control.NumericEditField
        InitialpositionEditFieldLabel  matlab.ui.control.Label
        InitialpositionEditField       matlab.ui.control.NumericEditField
        InitialVelocityEditFieldLabel  matlab.ui.control.Label
        InitialVelocityEditField       matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        % Function for torque
        function Tor1=Torquefun1(ti,q,qd,a)    %global jn;
            %global T;
                Tor1=T1;
            end
            
         %end
            %T=R.fkine(q);
            %ST=[T.n';T.o';T.a';T.t'];
            %T=ST';
            %R.plot(q,'workspace',Workspace)
            %app.UITable4.Data=T
            %assignin('base','T',app.UITable4.Data)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed2(app, event)
              global T;
          jn=app.NoofLinksEditField.Value;
          dh=app.UITable.Data;
          assignin('base','dh',dh)
          type_joint=app.UITable2.Data(:,1)'
          assignin('base','type_joint',type_joint)
          %Workspace=[app.XminEditField.Value app.XmaxEditField.Value app.YminEditField.Value app.YmaxEditField.Value app.ZminEditField.Value app.ZmaxEditField.Value]
          %assignin('base','Workspace',Workspace)
          %q=app.UITable.Data';
          %assignin('base','q',q)
          
          %for i=1:jn
           q0=app.UITable2.Data(:,5)';
           qdot0=app.UITable2.Data(:,6)';
           q0=q0*pi/180;
          %end
         
          for i=1:jn%length(type_joint)
%             if type_joint(1,i)==0
%                 L(i)=Link([dh(i,1),dh(i,2),dh(i,3),dh(i,4)])
%             else
%                 L(i)=Link([dh(i,1),dh(i,2),dh(i,3),dh(i,4),1])
%             end
                if type_joint(i)==0
                     L(i)=Link('d',dh(i,1),'a',dh(i,3),'alpha',dh(i,4),'m',dh(i,5),'r',[dh(i,6);0;0],'I',eye(3)*dh(i,7),'G',dh(i,8),'Jm',dh(i,9));
                else
                    L(i)=Link('theta',dh(i,1),'a',dh(i,3),'alpha',dh(i,4),'m',dh(i,5),'r',[dh(i,6);0;0],'I',eye(3)*dh(i,7),'G',dh(i,8),'Jm',dh(i,9));
                end
          end
          
          R = SerialLink(L)
          R.gravity=[0;0;0];
          tf=app.Timeforsimulation0EditField.Value;
          %for i=1:jn
          ang=zeros(jn,1);
          for i=1:jn
            %if type_joint(i)==0
            %     q(:,i)=q(:,i)*pi/180;
            %end
            %if type_joint(i)==0
                ang(i)=app.UITable2.Data(i,3)';
                %ang(i)=ang(i)*pi/180;
            %end
            if type_joint(i)==1
              pris(i,:)=app.UITable2.Data(i,4)';
              L(i).qlim=pris(i,:);
            end
          end
          
    
            T=app.UITable2.Data(:,2)';
            f=@Torquefun;
            %z=f(0,0,0,0);
            %f[:]=TorqueFun[];
            [ti,q,qd]=R.fdyn(tf,f,q0,qdot0)
            figure(1)
            qdeg=rad2deg(q);
            plot(ti,qdeg)
            hold on
            grid on
            xlabel('time(seconds)')
            ylabel('joint position q(degrees)')
            legend('q1','q2','q3')
            figure(2)
            plot(ti,qd)
            hold on
            grid on
            xlabel('time(seconds)')
            ylabel('joint Velocities qd(radians/sec)')
            legend('qd1','qd2','qd3')
            figure(3)
            %ang=ang*pi/180
            plot(R,ang');%app.UITable2.Data(:,3)');%,'workspace',[-1 1 -1 1]);%%[0 0 0]);
                % Function for torque
            function Tor=Torquefun(ti,q,qd,a)    %global jn;
            %global T;
                Tor=T;
            end       
           
         %end
            %T=R.fkine(q);
            %ST=[T.n';T.o';T.a';T.t'];
            %T=ST';
            %R.plot(q,'workspace',Workspace)
            %app.UITable4.Data=T
            %assignin('base','T',app.UITable4.Data)
       
        end

        % Button pushed function: FillRowButton
        function FillRowButtonPushed(app, event)
            app.UITable.Data(app.LinkNoEditField.Value,:) = [app.thetaEditField.Value,app.dEditField.Value,app.aEditField.Value,app.alpharadiansEditField.Value,app.MassoflinkEditField.Value,app.InertiaofLinkEditField.Value,app.GearRatioEditField.Value,app.InertiaofmassEditField.Value,app.LengthofCOGEditField.Value];
            app.UITable2.Data(app.LinkNoEditField.Value,:) = [app.JointTypeEditField.Value,app.TorqueEditField.Value,app.Jointangle0EditField.Value,app.JointDistanceEditField.Value,app.InitialpositionEditField.Value,app.InitialVelocityEditField.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'; 'Ml'; 'iL'; 'kr'; 'Im'; 'l'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'joint type'; 'Tau'; 'angle'; 'dist'; 'q0'; 'qdot0'};
            app.UITable2.RowName = {};
            app.NoofLinksEditFieldLabel = uilabel(app.UIFigure);
            app.NoofLinksEditFieldLabel.Text = 'No of Links';
            app.NoofLinksEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofLinksEditField.Value = 3;
            app.thetaEditFieldLabel = uilabel(app.UIFigure);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.Timeforsimulation0Label = uilabel(app.UIFigure);
            app.Timeforsimulation0Label.Text = {'Time for'; ' simulation  (>0)'};
            app.Timeforsimulation0EditField = uieditfield(app.UIFigure, 'numeric');
            app.TorqueEditFieldLabel = uilabel(app.UIFigure);
            app.TorqueEditFieldLabel.Text = 'Torque';
            app.TorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.alpharadiansEditFieldLabel = uilabel(app.UIFigure);
            app.alpharadiansEditFieldLabel.Text = {'alpha'; '(radians)'};
            app.alpharadiansEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkNoEditFieldLabel = uilabel(app.UIFigure);
            app.LinkNoEditFieldLabel.Text = 'Link No.';
            app.LinkNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.FillRowButton = uibutton(app.UIFigure, 'push');
            app.FillRowButton.ButtonPushedFcn = createCallbackFcn(app, @FillRowButtonPushed, true);
            app.FillRowButton.Text = 'Fill Row';
            app.Jointangle0EditFieldLabel = uilabel(app.UIFigure);
            app.Jointangle0EditFieldLabel.Text = 'Joint angle  (>0)';
            app.Jointangle0EditField = uieditfield(app.UIFigure, 'numeric');
            app.JointDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.JointDistanceEditFieldLabel.Text = 'Joint Distance';
            app.JointDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed2, true);
            app.RunButton.Text = 'Run';
            app.MassoflinkEditFieldLabel = uilabel(app.UIFigure);
            app.MassoflinkEditFieldLabel.Text = 'Mass of link';
            app.MassoflinkEditField = uieditfield(app.UIFigure, 'numeric');
            app.GearRatioEditFieldLabel = uilabel(app.UIFigure);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.GearRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.InertiaofmassEditFieldLabel = uilabel(app.UIFigure);
            app.InertiaofmassEditFieldLabel.Text = 'Inertia of mass';
            app.InertiaofmassEditField = uieditfield(app.UIFigure, 'numeric');
            app.InertiaofLinkEditFieldLabel = uilabel(app.UIFigure);
            app.InertiaofLinkEditFieldLabel.Text = 'Inertia of Link';
            app.InertiaofLinkEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthofCOGEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofCOGEditFieldLabel.Text = 'Length of COG';
            app.LengthofCOGEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointTypeEditFieldLabel = uilabel(app.UIFigure);
            app.JointTypeEditFieldLabel.Text = 'Joint Type';
            app.JointTypeEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialpositionEditFieldLabel = uilabel(app.UIFigure);
            app.InitialpositionEditFieldLabel.Text = 'Initial position';
            app.InitialpositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.InitialVelocityEditFieldLabel.Text = 'Initial Velocity';
            app.InitialVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dynamics11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end