classdef dynamics21 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NOOFLINKSEditFieldLabel      matlab.ui.control.Label
        NOOFLINKSEditField           matlab.ui.control.NumericEditField
        RUNButton                    matlab.ui.control.Button
        FillrowsButton               matlab.ui.control.Button
        TorqueEquationTextAreaLabel  matlab.ui.control.Label
        TorqueEquationTextArea       matlab.ui.control.TextArea
        UITable2                     matlab.ui.control.Table
        LinkNoEditFieldLabel         matlab.ui.control.Label
        LinkNoEditField              matlab.ui.control.NumericEditField
        dEditFieldLabel              matlab.ui.control.Label
        dEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        alpEditFieldLabel            matlab.ui.control.Label
        alpEditField                 matlab.ui.control.NumericEditField
        MassoflinkEditFieldLabel     matlab.ui.control.Label
        MassoflinkEditField          matlab.ui.control.NumericEditField
        LengthofCOGEditFieldLabel    matlab.ui.control.Label
        LengthofCOGEditField         matlab.ui.control.NumericEditField
        InertiaofLinkEditFieldLabel  matlab.ui.control.Label
        InertiaofLinkEditField       matlab.ui.control.NumericEditField
        GearratioEditFieldLabel      matlab.ui.control.Label
        GearratioEditField           matlab.ui.control.NumericEditField
        InertiaofMassEditFieldLabel  matlab.ui.control.Label
        InertiaofMassEditField       matlab.ui.control.NumericEditField
        JointtypeEditFieldLabel      matlab.ui.control.Label
        JointtypeEditField           matlab.ui.control.NumericEditField
        UITable3                     matlab.ui.control.Table
        thetaEditFieldLabel          matlab.ui.control.Label
        thetaEditField               matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FillrowsButton
        function FillrowsButtonPushed(app, event)
            jn=app.NOOFLINKSEditField.Value;
            syms q [1 jn] 
            syms qdot [1 jn] integer
            app.UITable2.Data(app.LinkNoEditField.Value,:) = [app.thetaEditField.Value,app.dEditField.Value,app.aEditField.Value,app.alpEditField.Value,app.MassoflinkEditField.Value,app.InertiaofLinkEditField.Value,app.GearratioEditField.Value,app.InertiaofMassEditField.Value,app.LengthofCOGEditField.Value];
            app.UITable3.Data(app.LinkNoEditField.Value,:) = app.JointtypeEditField.Value;
            %for i=1:jn
             %   app.UITable.Data(i,1)={q1,q2};
             %   app.UITable.Data(i,2)={q1,q2};
            %end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            jn=app.NOOFLINKSEditField.Value;
            
            syms q [1 jn]
            syms qdot [1 jn]
            syms qdotdot [1 jn]
            dh=app.UITable2.Data;
            type_joint=app.UITable3.Data';
            for i=1:jn
               
                if type_joint(i)==0
                     M(i)=Link('d',dh(i,2),'a',dh(i,3),'alpha',dh(i,4),'m',dh(i,5),'r',[dh(i,6);0;0],'I',eye(3)*dh(i,7),'G',dh(i,8),'Jm',dh(i,9));
                else
                    M(i)=Link('theta',dh(i,1),'a',dh(i,3),'alpha',dh(i,4),'m',dh(i,5),'r',[dh(i,6);0;0],'I',eye(3)*dh(i,7),'G',dh(i,8),'Jm',dh(i,9));
                end
            end
            
            robot = SerialLink(M)
            qnew=sym2cell(q);
            qdt=sym2cell(qdot);
            C=robot.coriolis(q,qdot);
            D=robot.inertia(q);
            F=robot.friction(q);
            G=robot.gravload(q);
            %thetadotdot=diff(thetadot)
            Tau=D*qdotdot.'+C*qdot.'+G+F  %qdotdot.'
            %Tau1=Tau(1,:);
            str=char(Tau);
            %str([1:8,end-1:end])=[];
            app.TorqueEquationTextArea.Value=str;%%Tau);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NOOFLINKSEditFieldLabel = uilabel(app.UIFigure);
            app.NOOFLINKSEditFieldLabel.Text = 'NO OF LINKS';
            app.NOOFLINKSEditField = uieditfield(app.UIFigure, 'numeric');
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.FillrowsButton = uibutton(app.UIFigure, 'push');
            app.FillrowsButton.ButtonPushedFcn = createCallbackFcn(app, @FillrowsButtonPushed, true);
            app.FillrowsButton.Text = 'Fill rows';
            app.TorqueEquationTextAreaLabel = uilabel(app.UIFigure);
            app.TorqueEquationTextAreaLabel.Text = {'Torque'; 'Equation:'};
            app.TorqueEquationTextArea = uitextarea(app.UIFigure);
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'; 'Ml'; 'iL'; 'kr'; 'Im'; 'L'};
            app.UITable2.RowName = {};
            app.LinkNoEditFieldLabel = uilabel(app.UIFigure);
            app.LinkNoEditFieldLabel.Text = 'Link No.';
            app.LinkNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.alpEditFieldLabel = uilabel(app.UIFigure);
            app.alpEditFieldLabel.Text = 'alp';
            app.alpEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassoflinkEditFieldLabel = uilabel(app.UIFigure);
            app.MassoflinkEditFieldLabel.Text = 'Mass of link';
            app.MassoflinkEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthofCOGEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofCOGEditFieldLabel.Text = ' Length of COG';
            app.LengthofCOGEditField = uieditfield(app.UIFigure, 'numeric');
            app.InertiaofLinkEditFieldLabel = uilabel(app.UIFigure);
            app.InertiaofLinkEditFieldLabel.Text = 'Inertia of Link';
            app.InertiaofLinkEditField = uieditfield(app.UIFigure, 'numeric');
            app.GearratioEditFieldLabel = uilabel(app.UIFigure);
            app.GearratioEditFieldLabel.Text = 'Gear ratio';
            app.GearratioEditField = uieditfield(app.UIFigure, 'numeric');
            app.InertiaofMassEditFieldLabel = uilabel(app.UIFigure);
            app.InertiaofMassEditFieldLabel.Text = 'Inertia of Mass';
            app.InertiaofMassEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointtypeEditFieldLabel = uilabel(app.UIFigure);
            app.JointtypeEditFieldLabel.Text = 'Joint type';
            app.JointtypeEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Joint type'};
            app.UITable3.RowName = {};
            app.thetaEditFieldLabel = uilabel(app.UIFigure);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dynamics21

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end