classdef rotmat_to_euler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InputPanel                    matlab.ui.container.Panel
        EnterrotationmatrixLabel      matlab.ui.control.Label
        EditField_7                   matlab.ui.control.NumericEditField
        SelectconfigurationLabel      matlab.ui.control.Label
        OrderofRotationDropDownLabel  matlab.ui.control.Label
        OrderofRotationDropDown       matlab.ui.control.DropDown
        ExecuteButton                 matlab.ui.control.Button
        EditField_8                   matlab.ui.control.NumericEditField
        EditField_9                   matlab.ui.control.NumericEditField
        EditField_10                  matlab.ui.control.NumericEditField
        EditField_11                  matlab.ui.control.NumericEditField
        EditField_12                  matlab.ui.control.NumericEditField
        EditField_13                  matlab.ui.control.NumericEditField
        EditField_14                  matlab.ui.control.NumericEditField
        EditField_15                  matlab.ui.control.NumericEditField
        OutputPanel                   matlab.ui.container.Panel
        OutputanglesLabel             matlab.ui.control.Label
        PhiEditFieldLabel             matlab.ui.control.Label
        PhiEditField                  matlab.ui.control.NumericEditField
        ThetaEditFieldLabel           matlab.ui.control.Label
        ThetaEditField                matlab.ui.control.NumericEditField
        PsiEditFieldLabel             matlab.ui.control.Label
        PsiEditField                  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            R = zeros(3);
            R(1,1) = app.EditField_7.Value;
            R(1,2) = app.EditField_8.Value;
            R(1,3) = app.EditField_9.Value;
            R(2,1) = app.EditField_10.Value;
            R(2,2) = app.EditField_11.Value;
            R(2,3) = app.EditField_12.Value;
            R(3,1) = app.EditField_13.Value;
            R(3,2) = app.EditField_14.Value;
            R(3,3) = app.EditField_15.Value;
            order = app.OrderofRotationDropDown.Value;
            

%Form of Rot_mat = [r11 r12 r13;...
%                   r21 r22 r23;...
%                   r31 r32 r33];

% R = input("Enter 3*3 rotation matrix:");
% order = input('Enter either of 12 Euler angles possibilities: ','s'); 

     switch order
         case 'XYZ'             
             X = round(rad2deg(atan2(-R(2,3),R(3,3)))); a = X;
             Y = round(rad2deg(asin(R(1,3)))); b = Y;
             Z = round(rad2deg(atan2(-R(1,2),R(1,1)))); c = Z;
         case 'XZY'
             X = round(rad2deg(atan2(R(3,2),R(2,2)))); a = X;
             Z = round(rad2deg(-asin(R(1,2)))); b = Z;
             Y = round(rad2deg(atan2(R(1,3),R(1,1)))); c = Y;
         case 'XYX'
             X = round(rad2deg(atan2(R(2,1),R(3,1)))); a = X;
             Y = round(rad2deg(acos(R(1,1)))); b = Y;
             Xp = round(rad2deg(atan2(R(1,2),R(1,3)))); c = Xp;
         case 'XZX'
             X = round(rad2deg(atan2(R(3,1),R(2,1)))); a = X;
             Z = round(rad2deg(acos(R(1,1)))); b = Z;
             Xp = round(rad2deg(atan2(R(1,3),-R(1,2)))); c = Xp;
         case 'YZX'
           Y = round(rad2deg(atan2(-R(3,1),R(1,1)))); a = Y;
           Z = round(rad2deg(asin(R(2,1)))); b = Z;
           X = round(rad2deg(atan2(-R(2,3),R(2,2)))); c = X; 
         case 'YXZ'
           Y = round(rad2deg(atan2(R(1,3),R(3,3)))); a = Y;
           X = round(rad2deg(-asin(R(2,3)))); b = X;
           Z = round(rad2deg(atan2(R(2,1),R(2,2)))); c = Z;
         case 'YXY'
           Y = round(rad2deg(atan2(R(1,2),R(3,2)))); a = Y;
           X = round(rad2deg(acos(R(2,2)))); b = X;
           Yp = round(rad2deg(atan2(R(2,1),-R(2,3)))); c = Yp;
         case 'YZY'
          Y = round(rad2deg(atan2(R(3,2),-R(1,2)))); a = Y;
          Z = round(rad2deg(acos(R(2,2)))); b = Z;
          Yp = round(rad2deg(atan2(R(2,3),R(2,1)))); c = Yp;
         case 'ZXY'
           Z = round(rad2deg(atan2(-R(1,2),R(2,2)))); a = Z;
           X = round(rad2deg(asin(R(3,2)))); b = X;
           Y = round(rad2deg(atan2(-R(3,1),R(3,3)))); c = Y; 
         case 'ZYX'
           Z = round(rad2deg(atan2(R(2,1),R(1,1)))); a = Z;
           Y = round(rad2deg(-asin(R(3,1)))); b = Y;
           X = round(rad2deg(atan2(R(3,2),R(3,3)))); c = X; 
         case 'ZXZ'
             Z = round(rad2deg(atan2(R(1,3),-R(2,3)))); a = Z;
             X = round(rad2deg(acos(R(3,3)))); b = X;
             Zp = round(rad2deg(atan2(R(3,1),R(3,2)))); c = Zp;
         case 'ZYZ'
             Z = round(rad2deg(atan2(R(2,3),R(1,3)))); a = Z;
             Y = round(rad2deg(acos(R(2,2)))); b = Y;
             Zp = round(rad2deg(atan2(R(3,2),-R(3,1)))); c = Zp;
         otherwise 
             disp('Unrecognized Transformation!')
     end
     app.PhiEditField.Value = a;
     app.ThetaEditField.Value = b;
     app.PsiEditField.Value = c;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.EnterrotationmatrixLabel = uilabel(app.InputPanel);
            app.EnterrotationmatrixLabel.Text = 'Enter rotation matrix:';
            app.EditField_7 = uieditfield(app.InputPanel, 'numeric');
            app.SelectconfigurationLabel = uilabel(app.InputPanel);
            app.SelectconfigurationLabel.Text = 'Select configuration';
            app.OrderofRotationDropDownLabel = uilabel(app.InputPanel);
            app.OrderofRotationDropDownLabel.Text = 'Order of Rotation';
            app.OrderofRotationDropDown = uidropdown(app.InputPanel);
            app.OrderofRotationDropDown.Items = {'XYZ', 'XZY', 'XYX', 'XZX', 'YZX', 'YXZ', 'YXY', 'YZY', 'ZXY', 'ZYX', 'ZXZ', 'ZYZ'};
            app.OrderofRotationDropDown.Value = 'XYZ';
            app.ExecuteButton = uibutton(app.InputPanel, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.EditField_8 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_9 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_10 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_11 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_12 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_13 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_14 = uieditfield(app.InputPanel, 'numeric');
            app.EditField_15 = uieditfield(app.InputPanel, 'numeric');
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.OutputanglesLabel = uilabel(app.OutputPanel);
            app.OutputanglesLabel.Text = 'Output angles';
            app.PhiEditFieldLabel = uilabel(app.OutputPanel);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.OutputPanel, 'numeric');
            app.ThetaEditFieldLabel = uilabel(app.OutputPanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.OutputPanel, 'numeric');
            app.PsiEditFieldLabel = uilabel(app.OutputPanel);
            app.PsiEditFieldLabel.Text = 'Psi';
            app.PsiEditField = uieditfield(app.OutputPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rotmat_to_euler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end