classdef workspace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotButton                      matlab.ui.control.Button
        StoredvariablesPanel            matlab.ui.container.Panel
        UITable3                        matlab.ui.control.Table
        UITable                         matlab.ui.control.Table
        NoteTextAreaLabel               matlab.ui.control.Label
        NoteTextArea                    matlab.ui.control.TextArea
        UITable2                        matlab.ui.control.Table
        InputsDHparametersandjointlimitsPanel  matlab.ui.container.Panel
        NumberoflinksEditFieldLabel     matlab.ui.control.Label
        NumberoflinksEditField          matlab.ui.control.NumericEditField
        avaluesEditFieldLabel           matlab.ui.control.Label
        avaluesEditField                matlab.ui.control.NumericEditField
        dvaluesEditFieldLabel           matlab.ui.control.Label
        dvaluesEditField                matlab.ui.control.NumericEditField
        alphavaluesEditFieldLabel       matlab.ui.control.Label
        alphavaluesEditField            matlab.ui.control.NumericEditField
        ThetavaluesEditFieldLabel       matlab.ui.control.Label
        ThetavaluesEditField            matlab.ui.control.NumericEditField
        qmaxEditFieldLabel              matlab.ui.control.Label
        qmaxEditField                   matlab.ui.control.NumericEditField
        LinkEditFieldLabel              matlab.ui.control.Label
        RowEditField                    matlab.ui.control.NumericEditField
        FillRowButton                   matlab.ui.control.Button
        qminLabel                       matlab.ui.control.Label
        qminEditField                   matlab.ui.control.NumericEditField
        JointtypeEditFieldLabel         matlab.ui.control.Label
        JointtypeEditField              matlab.ui.control.NumericEditField
        XminEditFieldLabel              matlab.ui.control.Label
        XminEditField                   matlab.ui.control.NumericEditField
        XmaxEditFieldLabel              matlab.ui.control.Label
        XmaxEditField                   matlab.ui.control.NumericEditField
        YmaxEditFieldLabel              matlab.ui.control.Label
        YmaxEditField                   matlab.ui.control.NumericEditField
        ZmaxEditFieldLabel              matlab.ui.control.Label
        ZmaxEditField                   matlab.ui.control.NumericEditField
        ZminEditFieldLabel              matlab.ui.control.Label
        ZminEditField                   matlab.ui.control.NumericEditField
        YminEditFieldLabel              matlab.ui.control.Label
        YminEditField                   matlab.ui.control.NumericEditField
        WorkspacesizeplotaxessizeLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            
        end

        % Callback function
        function UITableCellEdit(app, event)
            indices = event.Indices;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
          q=app.UITable2.Data'
          assignin('base','q',q)
          type_joint=app.UITable3.Data'
          assignin('base','type_joint',type_joint)
          Workspace=[app.XminEditField.Value app.XmaxEditField.Value app.YminEditField.Value app.YmaxEditField.Value app.ZminEditField.Value app.ZmaxEditField.Value]
          assignin('base','Workspace',Workspace)
          dh=app.UITable.Data;
          assignin('base','dh',dh)
          %Example
%q=[0 0 0;90 45 5];
%type_joint=[0 0 1];
%dh=[0 0 0 pi/2;0 0 5 0;0 0 5 0];
%Workspace=[-10 10 -10 10 -10 10];
%This code uses random value generation to construct the workspace of
%any robot configuration by taking advantage of the Peter Corke robotics
%toolbox
for i=1:length(type_joint) %this loop is used to convert the angles of revolute joints from degrees to radians.
    if type_joint(i)==0
        q(:,i)=q(:,i)*pi/180;
    end
end
for i=1:length(type_joint)
    W(:,i)=linspace(q(1,i),q(2,i),5000)
end

for i=1:length(type_joint) %this loop is used to construct the link parameters from the dh matrix
        if type_joint(1,i)==0
        L(i)=Link([dh(i,1),dh(i,2),dh(i,3),dh(i,4)]);
        else
        L(i)=Link([dh(i,1),dh(i,2),dh(i,3),dh(i,4),1]);
        end
    end
R = SerialLink(L);
for i=1:length(type_joint) %this loop is used to generate random values from the range of possible joint parameters
    G=W(:,i);
    R1(:,i)=G(randperm(numel(G),5000))
end
for i=1:5000
    T=R.fkine(R1(i,:))
R.plot(R1(i,:),'workspace', [Workspace])
PM=transl(T);
            PMx(i)=PM(1);
            PMy(i)=PM(2);
            PMz(i)=PM(3);
            scatter3(PMx,PMy,PMz,'.')
            hold on
end    
        end

        % Callback function
        function FillTableButtonPushed(app, event)
        

        end

        % Value changed function: avaluesEditField
        function avaluesEditFieldValueChanged(app, event)
            value = app.avaluesEditField.Value;
            
        end

        % Value changed function: NumberoflinksEditField
        function NumberoflinksEditFieldValueChanged(app, event)
            value = app.NumberoflinksEditField.Value;
            
        end

        % Value changed function: ThetavaluesEditField
        function ThetavaluesEditFieldValueChanged(app, event)
            value = app.ThetavaluesEditField.Value;
            
        end

        % Button pushed function: FillRowButton
        function FillRowButtonPushed(app, event)
                      app.UITable.Data(app.RowEditField.Value,:) = [app.ThetavaluesEditField.Value,app.dvaluesEditField.Value,app.avaluesEditField.Value,app.alphavaluesEditField.Value]
                      app.UITable2.Data(app.RowEditField.Value,1:2) = [app.qminEditField.Value,app.qmaxEditField.Value]
                      app.UITable3.Data(app.RowEditField.Value,1) = [app.JointtypeEditField.Value]

        end

        % Value changed function: RowEditField
        function RowEditFieldValueChanged(app, event)
            value = app.RowEditField.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
                      
        end

        % Value changed function: qminEditField
        function qminEditFieldValueChanged(app, event)
            value = app.qminEditField.Value;
            
        end

        % Cell edit callback: UITable3
        function UITable3CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            indices = event.Indices;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StoredvariablesPanel = uipanel(app.UIFigure);
            app.StoredvariablesPanel.Title = 'Stored variables';
            app.UITable3 = uitable(app.StoredvariablesPanel);
            app.UITable3.ColumnName = {'Joint type'};
            app.UITable3.RowName = {};
            app.UITable3.CellEditCallback = createCallbackFcn(app, @UITable3CellEdit, true);
            app.UITable = uitable(app.StoredvariablesPanel);
            app.UITable.ColumnName = {'Theta'; 'd'; 'a'; 'alpha'};
            app.UITable.RowName = {};
            app.NoteTextAreaLabel = uilabel(app.StoredvariablesPanel);
            app.NoteTextAreaLabel.Text = 'Note :';
            app.NoteTextArea = uitextarea(app.StoredvariablesPanel);
            app.NoteTextArea.Value = {'Fill up your your variables for each link and update the matrix with the corresponding link number.'; 'For joint type: type 0 for revolute or 1 for prismatic'};
            app.UITable2 = uitable(app.StoredvariablesPanel);
            app.UITable2.ColumnName = {'q min'; 'q max'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.InputsDHparametersandjointlimitsPanel = uipanel(app.UIFigure);
            app.InputsDHparametersandjointlimitsPanel.Title = 'Inputs(DH parameters and joint limits)';
            app.NumberoflinksEditFieldLabel = uilabel(app.InputsDHparametersandjointlimitsPanel);
            app.NumberoflinksEditFieldLabel.Text = 'Number of links';
            app.NumberoflinksEditField = uieditfield(app.InputsDHparametersandjointlimitsPanel, 'numeric');
            app.NumberoflinksEditField.ValueChangedFcn = createCallbackFcn(app, @NumberoflinksEditFieldValueChanged, true);
            app.avaluesEditFieldLabel = uilabel(app.InputsDHparametersandjointlimitsPanel);
            app.avaluesEditFieldLabel.Text = 'a values';
            app.avaluesEditField = uieditfield(app.InputsDHparametersandjointlimitsPanel, 'numeric');
            app.avaluesEditField.ValueChangedFcn = createCallbackFcn(app, @avaluesEditFieldValueChanged, true);
            app.dvaluesEditFieldLabel = uilabel(app.InputsDHparametersandjointlimitsPanel);
            app.dvaluesEditFieldLabel.Text = 'd values';
            app.dvaluesEditField = uieditfield(app.InputsDHparametersandjointlimitsPanel, 'numeric');
            app.alphavaluesEditFieldLabel = uilabel(app.InputsDHparametersandjointlimitsPanel);
            app.alphavaluesEditFieldLabel.Text = 'alpha values';
            app.alphavaluesEditField = uieditfield(app.InputsDHparametersandjointlimitsPanel, 'numeric');
            app.ThetavaluesEditFieldLabel = uilabel(app.InputsDHparametersandjointlimitsPanel);
            app.ThetavaluesEditFieldLabel.Text = 'Theta values';
            app.ThetavaluesEditField = uieditfield(app.InputsDHparametersandjointlimitsPanel, 'numeric');
            app.ThetavaluesEditField.ValueChangedFcn = createCallbackFcn(app, @ThetavaluesEditFieldValueChanged, true);
            app.qmaxEditFieldLabel = uilabel(app.InputsDHparametersandjointlimitsPanel);
            app.qmaxEditFieldLabel.Text = 'q max';
            app.qmaxEditField = uieditfield(app.InputsDHparametersandjointlimitsPanel, 'numeric');
            app.LinkEditFieldLabel = uilabel(app.InputsDHparametersandjointlimitsPanel);
            app.LinkEditFieldLabel.Text = 'Link';
            app.RowEditField = uieditfield(app.InputsDHparametersandjointlimitsPanel, 'numeric');
            app.RowEditField.ValueChangedFcn = createCallbackFcn(app, @RowEditFieldValueChanged, true);
            app.FillRowButton = uibutton(app.InputsDHparametersandjointlimitsPanel, 'push');
            app.FillRowButton.ButtonPushedFcn = createCallbackFcn(app, @FillRowButtonPushed, true);
            app.FillRowButton.Text = 'Fill Row';
            app.qminLabel = uilabel(app.InputsDHparametersandjointlimitsPanel);
            app.qminLabel.Text = 'q min';
            app.qminEditField = uieditfield(app.InputsDHparametersandjointlimitsPanel, 'numeric');
            app.qminEditField.ValueChangedFcn = createCallbackFcn(app, @qminEditFieldValueChanged, true);
            app.JointtypeEditFieldLabel = uilabel(app.InputsDHparametersandjointlimitsPanel);
            app.JointtypeEditFieldLabel.Text = 'Joint type';
            app.JointtypeEditField = uieditfield(app.InputsDHparametersandjointlimitsPanel, 'numeric');
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'X min';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'X max';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmaxEditFieldLabel = uilabel(app.UIFigure);
            app.YmaxEditFieldLabel.Text = 'Y max';
            app.YmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmaxEditFieldLabel = uilabel(app.UIFigure);
            app.ZmaxEditFieldLabel.Text = 'Z max';
            app.ZmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZminEditFieldLabel = uilabel(app.UIFigure);
            app.ZminEditFieldLabel.Text = 'Z min';
            app.ZminEditField = uieditfield(app.UIFigure, 'numeric');
            app.YminEditFieldLabel = uilabel(app.UIFigure);
            app.YminEditFieldLabel.Text = 'Y min';
            app.YminEditField = uieditfield(app.UIFigure, 'numeric');
            app.WorkspacesizeplotaxessizeLabel = uilabel(app.UIFigure);
            app.WorkspacesizeplotaxessizeLabel.Text = 'Workspace size (plot axes size)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = workspace

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end